classdef Chirag_Patel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditField_8                     matlab.ui.control.NumericEditField
        MotorStepSizeButton             matlab.ui.control.Button
        MotorStepSizeSlider             matlab.ui.control.Slider
        MotorStepSizeSliderLabel        matlab.ui.control.Label
        dBmLabel                        matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        MotorInformationButton          matlab.ui.control.Button
        Lamp_5                          matlab.ui.control.Lamp
        ArcusMotorButton                matlab.ui.control.StateButton
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        GettheFrequencyforMarkerButton  matlab.ui.control.Button
        EditField_5                     matlab.ui.control.NumericEditField
        EditField2_2                    matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        PowerButton                     matlab.ui.control.Button
        FrequencyButton                 matlab.ui.control.Button
        Lamp_2                          matlab.ui.control.Lamp
        GeneratorButton                 matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        GenRFopONOFFButton              matlab.ui.control.StateButton
        EditField_4                     matlab.ui.control.NumericEditField
        GetthePowerforMarkerButton      matlab.ui.control.Button
        ManualMarkerOptionsCheckBox     matlab.ui.control.CheckBox
        EditField5                      matlab.ui.control.NumericEditField
        EditField4                      matlab.ui.control.NumericEditField
        EditField3_2                    matlab.ui.control.NumericEditField
        SpanButton                      matlab.ui.control.Button
        BWButton                        matlab.ui.control.Button
        CenterFreqButton                matlab.ui.control.Button
        EditField3                      matlab.ui.control.NumericEditField
        EditField2                      matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        Lamp_4                          matlab.ui.control.Lamp
        ConstantPeakSearchMarkerButton  matlab.ui.control.StateButton
        MarkerButton                    matlab.ui.control.Button
        MarkerOFFButton                 matlab.ui.control.Button
        NextPeakButton                  matlab.ui.control.Button
        ModePresetButton                matlab.ui.control.Button
        AutoTuneButton                  matlab.ui.control.Button
        Lamp_3                          matlab.ui.control.Lamp
        AllMarkersOFFButton             matlab.ui.control.Button
        PeakSearchButton                matlab.ui.control.Button
        AnalyzerButton                  matlab.ui.control.StateButton
    end

    
    properties (Access = public)
        obj1;
        obj;
        interface = 0;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: GeneratorButton
        function GeneratorButtonValueChanged(app, event)
            value = app.GeneratorButton.Value;
            if (value==1)
                app.obj1 = Generator_MXG_N5182A();
                app.Lamp_2.Color='green';
            else
                app.obj1.Disconnect_Generator();
                app.Lamp_2.Color='red';
            end
        end

        % Value changed function: GenRFopONOFFButton
        function GenRFopONOFFButtonValueChanged(app, event)
            value = app.GenRFopONOFFButton.Value;
            if (value==1)
                app.obj1.RF_OutPut_ON();
                app.Lamp.Color='green';
            else
                app.obj1.RF_OutPut_OFF();
                app.Lamp.Color='red';
            end
        end

        % Value changed function: AnalyzerButton
        function AnalyzerButtonValueChanged(app, event)
            value = app.AnalyzerButton.Value;
            if (value==1)
                app.obj = Analyzer_EXA_N9010A();
                app.Lamp_3.Color='green';
            else
                app.obj.Disconnect_Analyzer();
                app.Lamp_3.Color='red';
            end
        end

        % Button pushed function: PeakSearchButton
        function PeakSearchButtonPushed(app, event)
            app.obj.Peak_Search(app.EditField2.Value);
        end

        % Button pushed function: AllMarkersOFFButton
        function AllMarkersOFFButtonPushed(app, event)
            app.obj.Turn_off_All_Markers();
        end

        % Button pushed function: AutoTuneButton
        function AutoTuneButtonPushed(app, event)
            app.obj.Auto_Tune();
        end

        % Button pushed function: ModePresetButton
        function ModePresetButtonPushed(app, event)
            app.obj.Mode_Preset();
        end

        % Button pushed function: NextPeakButton
        function NextPeakButtonPushed(app, event)
            app.obj.Next_Position(app.EditField3.Value);
        end

        % Button pushed function: MarkerOFFButton
        function MarkerOFFButtonPushed(app, event)
            app.obj.Turn_off_Marker(app.EditField_2.Value);
        end

        % Button pushed function: MarkerButton
        function MarkerButtonPushed(app, event)
            app.obj.Set_Marker(app.EditField.Value);
        end

        % Value changed function: ConstantPeakSearchMarkerButton
        function ConstantPeakSearchMarkerButtonValueChanged(app, event)
            value = app.ConstantPeakSearchMarkerButton.Value;
            if (value==1)
                app.obj.Set_Marker_onPeak(1);
                app.Lamp_4.Color='green';
            else
                app.obj.Turn_off_Marker(1);
                app.Lamp_4.Color='red';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
          app.obj.Set_Marker(app.EditField.Value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.obj.Turn_off_Marker(value);
        end

        % Value changed function: EditField3
        function EditField3ValueChanged(app, event)
            value = app.EditField3.Value;
            app.obj.Next_Position(value);
        end

        % Value changed function: EditField2
        function EditField2ValueChanged(app, event)
            value = app.EditField2.Value;
            app.obj.Peak_Search(value);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            app.obj1.SetFrequeny(value);
        end

        % Button pushed function: FrequencyButton
        function FrequencyButtonPushed(app, event)
            app.obj1.SetFrequeny(app.EditField_3.Value);
        end

        % Value changed function: EditField2_2
        function EditField2_2ValueChanged(app, event)
            value = app.EditField2_2.Value;
            app.obj1.SetPowerLevel(value);
        end

        % Button pushed function: PowerButton
        function PowerButtonPushed(app, event)
            app.obj1.SetPowerLevel(app.EditField2_2.Value);
        end

        % Value changed function: EditField3_2
        function EditField3_2ValueChanged(app, event)
            value = app.EditField3_2.Value;
            app.obj.Set_Center_Frequency(value);
        end

        % Button pushed function: CenterFreqButton
        function CenterFreqButtonPushed(app, event)
            app.obj.Set_Center_Frequency(app.EditField3_2.Value);
        end

        % Value changed function: EditField4
        function EditField4ValueChanged(app, event)
            value = app.EditField4.Value;
            app.obj.Set_BnadWidth(value);
        end

        % Button pushed function: BWButton
        function BWButtonPushed(app, event)
            app.obj.Set_BnadWidth(app.EditField4.Value);
        end

        % Value changed function: EditField5
        function EditField5ValueChanged(app, event)
            value = app.EditField5.Value;
            app.obj.Set_Span(value);
        end

        % Button pushed function: SpanButton
        function SpanButtonPushed(app, event)
            app.obj.Set_Span(app.EditField5.Value);
        end

        % Value changed function: ManualMarkerOptionsCheckBox
        function ManualMarkerOptionsCheckBoxValueChanged(app, event)
            value = app.ManualMarkerOptionsCheckBox.Value;
            switch value
                case 0
                    app.MarkerButton.Visible        = 'OFF';
                    app.MarkerOFFButton.Visible     = 'OFF';
                    app.AllMarkersOFFButton.Visible = 'OFF';
                    app.NextPeakButton.Visible      = 'OFF';
                    app.PeakSearchButton.Visible    = 'OFF';
                    app.EditField.Visible   = 'OFF';
                    app.EditField_2.Visible = 'OFF';
                    app.EditField3.Visible  = 'OFF';
                    app.EditField2.Visible  = 'OFF';
                case 1
                    app.MarkerButton.Visible        = 'ON';
                    app.MarkerOFFButton.Visible     = 'ON';
                    app.AllMarkersOFFButton.Visible = 'ON';
                    app.NextPeakButton.Visible      = 'ON';
                    app.PeakSearchButton.Visible    = 'ON';
                    app.EditField.Visible   = 'ON';
                    app.EditField_2.Visible = 'ON';
                    app.EditField3.Visible  = 'ON';
                    app.EditField2.Visible  = 'ON';
            end
        end

        % Button pushed function: GetthePowerforMarkerButton
        function GetthePowerforMarkerButtonPushed(app, event)
           app.EditField_4.Value = str2double(app.obj.Get_Marker_Power(app.EditField_5.Value));           
        end

        % Button pushed function: GettheFrequencyforMarkerButton
        function GettheFrequencyforMarkerButtonPushed(app, event)
            app.EditField_7.Value = str2double(app.obj.Get_Marker_Freq(app.EditField_6.Value));
        end

        % Value changed function: ArcusMotorButton
        function ArcusMotorButtonValueChanged(app, event)
            value = app.ArcusMotorButton.Value;
            if (value==1)
                run ("MyArcus.m");
                MyArcus.Enable();
                app.Lamp_5.Color='green';
            else
                MyArcus.Disable();
                app.Lamp_5.Color='red';
            end
        end

        % Button pushed function: MotorInformationButton
        function MotorInformationButtonPushed(app, event)
            app.TextArea.Value = struct2array(MyArcus.DevInfo);
        end

        % Value changed function: MotorStepSizeSlider
        function MotorStepSizeSliderValueChanged(app, event)
            app.EditField_8.Value = app.MotorStepSizeSlider.Value;
            MyArcus.PositionTo(app.EditField_8.Value);
        end

        % Button pushed function: MotorStepSizeButton
        function MotorStepSizeButtonPushed(app, event)
            MyArcus.PositionTo(app.EditField_8.Value);
        end

        % Value changed function: EditField_8
        function EditField_8ValueChanged(app, event)
            value = app.EditField_8.Value;
            app.MotorStepSizeSlider.Value = app.EditField_8.Value;
            MyArcus.PositionTo(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AnalyzerButton = uibutton(app.UIFigure, 'state');
            app.AnalyzerButton.ValueChangedFcn = createCallbackFcn(app, @AnalyzerButtonValueChanged, true);
            app.AnalyzerButton.Text = 'Analyzer  ';
            app.PeakSearchButton = uibutton(app.UIFigure, 'push');
            app.PeakSearchButton.ButtonPushedFcn = createCallbackFcn(app, @PeakSearchButtonPushed, true);
            app.PeakSearchButton.Visible = 'off';
            app.PeakSearchButton.Text = '  Peak Search';
            app.AllMarkersOFFButton = uibutton(app.UIFigure, 'push');
            app.AllMarkersOFFButton.ButtonPushedFcn = createCallbackFcn(app, @AllMarkersOFFButtonPushed, true);
            app.AllMarkersOFFButton.Visible = 'off';
            app.AllMarkersOFFButton.Text = 'All Markers OFF';
            app.Lamp_3 = uilamp(app.UIFigure);
            app.AutoTuneButton = uibutton(app.UIFigure, 'push');
            app.AutoTuneButton.ButtonPushedFcn = createCallbackFcn(app, @AutoTuneButtonPushed, true);
            app.AutoTuneButton.Text = 'Auto Tune';
            app.ModePresetButton = uibutton(app.UIFigure, 'push');
            app.ModePresetButton.ButtonPushedFcn = createCallbackFcn(app, @ModePresetButtonPushed, true);
            app.ModePresetButton.Text = 'Mode Preset';
            app.NextPeakButton = uibutton(app.UIFigure, 'push');
            app.NextPeakButton.ButtonPushedFcn = createCallbackFcn(app, @NextPeakButtonPushed, true);
            app.NextPeakButton.Visible = 'off';
            app.NextPeakButton.Text = '  Next Peak';
            app.MarkerOFFButton = uibutton(app.UIFigure, 'push');
            app.MarkerOFFButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerOFFButtonPushed, true);
            app.MarkerOFFButton.Visible = 'off';
            app.MarkerOFFButton.Text = '  Marker OFF';
            app.MarkerButton = uibutton(app.UIFigure, 'push');
            app.MarkerButton.ButtonPushedFcn = createCallbackFcn(app, @MarkerButtonPushed, true);
            app.MarkerButton.Visible = 'off';
            app.MarkerButton.Text = '     Marker';
            app.ConstantPeakSearchMarkerButton = uibutton(app.UIFigure, 'state');
            app.ConstantPeakSearchMarkerButton.ValueChangedFcn = createCallbackFcn(app, @ConstantPeakSearchMarkerButtonValueChanged, true);
            app.ConstantPeakSearchMarkerButton.Text = {' Constant Peak'; 'Search Marker'};
            app.Lamp_4 = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.Limits = [1 5];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Visible = 'off';
            app.EditField.Value = 1;
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.Limits = [1 5];
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Visible = 'off';
            app.EditField_2.Value = 1;
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2.Limits = [1 5];
            app.EditField2.ValueChangedFcn = createCallbackFcn(app, @EditField2ValueChanged, true);
            app.EditField2.Visible = 'off';
            app.EditField2.Value = 1;
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3.Limits = [1 5];
            app.EditField3.ValueChangedFcn = createCallbackFcn(app, @EditField3ValueChanged, true);
            app.EditField3.Visible = 'off';
            app.EditField3.Value = 1;
            app.CenterFreqButton = uibutton(app.UIFigure, 'push');
            app.CenterFreqButton.ButtonPushedFcn = createCallbackFcn(app, @CenterFreqButtonPushed, true);
            app.CenterFreqButton.Text = {'  Center '; '   Freq.'};
            app.BWButton = uibutton(app.UIFigure, 'push');
            app.BWButton.ButtonPushedFcn = createCallbackFcn(app, @BWButtonPushed, true);
            app.BWButton.Text = '  BW';
            app.SpanButton = uibutton(app.UIFigure, 'push');
            app.SpanButton.ButtonPushedFcn = createCallbackFcn(app, @SpanButtonPushed, true);
            app.SpanButton.Text = '  Span';
            app.EditField3_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3_2.ValueChangedFcn = createCallbackFcn(app, @EditField3_2ValueChanged, true);
            app.EditField3_2.Value = 3500000000;
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4.ValueChangedFcn = createCallbackFcn(app, @EditField4ValueChanged, true);
            app.EditField4.Value = 3000000;
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5.ValueChangedFcn = createCallbackFcn(app, @EditField5ValueChanged, true);
            app.EditField5.Value = 7000000000;
            app.ManualMarkerOptionsCheckBox = uicheckbox(app.UIFigure);
            app.ManualMarkerOptionsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualMarkerOptionsCheckBoxValueChanged, true);
            app.ManualMarkerOptionsCheckBox.Text = {'  Manual Marker'; '      Options'};
            app.GetthePowerforMarkerButton = uibutton(app.UIFigure, 'push');
            app.GetthePowerforMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @GetthePowerforMarkerButtonPushed, true);
            app.GetthePowerforMarkerButton.Text = {'  Get the Power '; '     for Marker'};
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4.Editable = 'off';
            app.GenRFopONOFFButton = uibutton(app.UIFigure, 'state');
            app.GenRFopONOFFButton.ValueChangedFcn = createCallbackFcn(app, @GenRFopONOFFButtonValueChanged, true);
            app.GenRFopONOFFButton.Tag = 'state button on and off';
            app.GenRFopONOFFButton.Text = {'Gen. RF    '; 'o/p ON/OFF'};
            app.Lamp = uilamp(app.UIFigure);
            app.GeneratorButton = uibutton(app.UIFigure, 'state');
            app.GeneratorButton.ValueChangedFcn = createCallbackFcn(app, @GeneratorButtonValueChanged, true);
            app.GeneratorButton.Text = 'Generator ';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.FrequencyButton = uibutton(app.UIFigure, 'push');
            app.FrequencyButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyButtonPushed, true);
            app.FrequencyButton.Text = 'Frequency';
            app.PowerButton = uibutton(app.UIFigure, 'push');
            app.PowerButton.ButtonPushedFcn = createCallbackFcn(app, @PowerButtonPushed, true);
            app.PowerButton.Text = '  Power';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_3.Value = 1000000000;
            app.EditField2_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2_2.ValueChangedFcn = createCallbackFcn(app, @EditField2_2ValueChanged, true);
            app.EditField2_2.Value = 10;
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5.Value = 1;
            app.GettheFrequencyforMarkerButton = uibutton(app.UIFigure, 'push');
            app.GettheFrequencyforMarkerButton.ButtonPushedFcn = createCallbackFcn(app, @GettheFrequencyforMarkerButtonPushed, true);
            app.GettheFrequencyforMarkerButton.Text = {'Get the Frequency'; '      for Marker'};
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6.Value = 1;
            app.EditField_7 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_7.Editable = 'off';
            app.ArcusMotorButton = uibutton(app.UIFigure, 'state');
            app.ArcusMotorButton.ValueChangedFcn = createCallbackFcn(app, @ArcusMotorButtonValueChanged, true);
            app.ArcusMotorButton.Text = {'Arcus    '; 'Motor    '};
            app.Lamp_5 = uilamp(app.UIFigure);
            app.MotorInformationButton = uibutton(app.UIFigure, 'push');
            app.MotorInformationButton.ButtonPushedFcn = createCallbackFcn(app, @MotorInformationButtonPushed, true);
            app.MotorInformationButton.Text = {' Motor'; 'Information'};
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.dBmLabel = uilabel(app.UIFigure);
            app.dBmLabel.Text = 'dBm';
            app.MotorStepSizeSliderLabel = uilabel(app.UIFigure);
            app.MotorStepSizeSliderLabel.Text = 'Motor Step Size';
            app.MotorStepSizeSlider = uislider(app.UIFigure);
            app.MotorStepSizeSlider.Limits = [0 360];
            app.MotorStepSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MotorStepSizeSliderValueChanged, true);
            app.MotorStepSizeButton = uibutton(app.UIFigure, 'push');
            app.MotorStepSizeButton.ButtonPushedFcn = createCallbackFcn(app, @MotorStepSizeButtonPushed, true);
            app.MotorStepSizeButton.Text = {'Motor Step'; '     Size'};
            app.EditField_8 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_8.ValueChangedFcn = createCallbackFcn(app, @EditField_8ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Chirag_Patel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end